classdef GUI_pEQ_live < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralvolumecomparisonTab      matlab.ui.container.Tab
        SampleFrequencyVolumeEdit       matlab.ui.control.EditField
        SampleFrequency1sEditField_2Label  matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        NoisemodeButtonGroup            matlab.ui.container.ButtonGroup
        WhitenoiseButton                matlab.ui.control.ToggleButton
        RosanoiseButton                 matlab.ui.control.ToggleButton
        AWeightedButton                 matlab.ui.control.ToggleButton
        SliderVolume                    matlab.ui.control.Slider
        VolumeLabel                     matlab.ui.control.Label
        DiagramFilteredFFTVolume        matlab.ui.control.UIAxes
        DiagramUnfilteredFFTVolume      matlab.ui.control.UIAxes
        DiagramFilteredNoiseVolume      matlab.ui.control.UIAxes
        DiagramUnfilteredNoiseVolume    matlab.ui.control.UIAxes
        DiagramTF_Volume                matlab.ui.control.UIAxes
        SinglebandcomparisonTab         matlab.ui.container.Tab
        SampleFrequency1sEditField      matlab.ui.control.EditField
        SampleFrequency1sEditFieldLabel  matlab.ui.control.Label
        SignaldurationsEditField        matlab.ui.control.EditField
        SignaldurationsEditFieldLabel   matlab.ui.control.Label
        BtnSaveBandGain                 matlab.ui.control.Button
        BtnPlayNoise                    matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        BtnGenNoise                     matlab.ui.control.Button
        BtnStopNoise                    matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Band10Button                    matlab.ui.control.RadioButton
        Band9Button                     matlab.ui.control.RadioButton
        Band8Button                     matlab.ui.control.RadioButton
        Band7Button                     matlab.ui.control.RadioButton
        Band6Button                     matlab.ui.control.RadioButton
        Band5Button                     matlab.ui.control.RadioButton
        Band4Button                     matlab.ui.control.RadioButton
        Band3Button                     matlab.ui.control.RadioButton
        Band2Button                     matlab.ui.control.RadioButton
        Band1Button                     matlab.ui.control.RadioButton
        DiagramFilteredFFTNoise         matlab.ui.control.UIAxes
        DiagramUnfilteredFFTNoise       matlab.ui.control.UIAxes
        DiagramFilteredNoise            matlab.ui.control.UIAxes
        DiagramUnfilteredNoise          matlab.ui.control.UIAxes
        DiagramTF_Noise                 matlab.ui.control.UIAxes
        EqualizerTab                    matlab.ui.container.Tab
        Panelf1                         matlab.ui.container.Panel
        SpnN1                           matlab.ui.control.Spinner
        OrderSpinnerLabel               matlab.ui.control.Label
        SpnBw1                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel           matlab.ui.control.Label
        SpnF1                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel     matlab.ui.control.Label
        Editf1                          matlab.ui.control.EditField
        dBEditFieldLabel_10             matlab.ui.control.Label
        Sliderf1                        matlab.ui.control.Slider
        Panelf2                         matlab.ui.container.Panel
        SpnF2                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_11  matlab.ui.control.Label
        SpnBw2                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_11        matlab.ui.control.Label
        SpnN2                           matlab.ui.control.Spinner
        OrderSpinnerLabel_11            matlab.ui.control.Label
        Editf2                          matlab.ui.control.EditField
        dBEditFieldLabel_9              matlab.ui.control.Label
        Sliderf2                        matlab.ui.control.Slider
        Panelf3                         matlab.ui.container.Panel
        SpnF3                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_3   matlab.ui.control.Label
        SpnBw3                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_3         matlab.ui.control.Label
        SpnN3                           matlab.ui.control.Spinner
        OrderSpinnerLabel_3             matlab.ui.control.Label
        Editf3                          matlab.ui.control.EditField
        dBEditFieldLabel_8              matlab.ui.control.Label
        Sliderf3                        matlab.ui.control.Slider
        Panelf4                         matlab.ui.container.Panel
        SpnF4                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_4   matlab.ui.control.Label
        SpnBw4                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_4         matlab.ui.control.Label
        SpnN4                           matlab.ui.control.Spinner
        OrderSpinnerLabel_4             matlab.ui.control.Label
        Editf4                          matlab.ui.control.EditField
        dBEditFieldLabel_7              matlab.ui.control.Label
        Sliderf4                        matlab.ui.control.Slider
        Panelf5                         matlab.ui.container.Panel
        SpnF5                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_5   matlab.ui.control.Label
        SpnBw5                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_5         matlab.ui.control.Label
        SpnN5                           matlab.ui.control.Spinner
        OrderSpinnerLabel_5             matlab.ui.control.Label
        Editf5                          matlab.ui.control.EditField
        dBEditFieldLabel_6              matlab.ui.control.Label
        Sliderf5                        matlab.ui.control.Slider
        Panelf6                         matlab.ui.container.Panel
        SpnF6                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_6   matlab.ui.control.Label
        SpnBw6                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_6         matlab.ui.control.Label
        SpnN6                           matlab.ui.control.Spinner
        OrderSpinnerLabel_6             matlab.ui.control.Label
        Editf6                          matlab.ui.control.EditField
        dBEditFieldLabel_5              matlab.ui.control.Label
        Sliderf6                        matlab.ui.control.Slider
        Panelf7                         matlab.ui.container.Panel
        SpnF7                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_7   matlab.ui.control.Label
        SpnBw7                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_7         matlab.ui.control.Label
        SpnN7                           matlab.ui.control.Spinner
        OrderSpinnerLabel_7             matlab.ui.control.Label
        Editf7                          matlab.ui.control.EditField
        dBEditFieldLabel_4              matlab.ui.control.Label
        Sliderf7                        matlab.ui.control.Slider
        Panelf8                         matlab.ui.container.Panel
        SpnF8                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_8   matlab.ui.control.Label
        SpnBw8                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_8         matlab.ui.control.Label
        SpnN8                           matlab.ui.control.Spinner
        OrderSpinnerLabel_8             matlab.ui.control.Label
        Editf8                          matlab.ui.control.EditField
        dBEditFieldLabel_3              matlab.ui.control.Label
        Sliderf8                        matlab.ui.control.Slider
        Panelf9                         matlab.ui.container.Panel
        SpnF9                           matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_9   matlab.ui.control.Label
        SpnBw9                          matlab.ui.control.Spinner
        BandwidthSpinnerLabel_9         matlab.ui.control.Label
        SpnN9                           matlab.ui.control.Spinner
        OrderSpinnerLabel_9             matlab.ui.control.Label
        Editf9                          matlab.ui.control.EditField
        dBEditFieldLabel_2              matlab.ui.control.Label
        Sliderf9                        matlab.ui.control.Slider
        Panelf10                        matlab.ui.container.Panel
        SpnF10                          matlab.ui.control.Spinner
        CenterFrequencySpinnerLabel_10  matlab.ui.control.Label
        SpnBw10                         matlab.ui.control.Spinner
        BandwidthSpinnerLabel_10        matlab.ui.control.Label
        SpnN10                          matlab.ui.control.Spinner
        OrderSpinnerLabel_10            matlab.ui.control.Label
        Editf10                         matlab.ui.control.EditField
        dBEditFieldLabel                matlab.ui.control.Label
        Sliderf10                       matlab.ui.control.Slider
        ResetFButton                    matlab.ui.control.Button
        ResetOrderButton                matlab.ui.control.Button
        ResetGainsButton                matlab.ui.control.Button
        BtnPlay                         matlab.ui.control.Button
        BtnStop                         matlab.ui.control.Button
        BtnResetBw                      matlab.ui.control.Button
        BtnSave                         matlab.ui.control.Button
        DiagramTF                       matlab.ui.control.UIAxes
        DiagramFilteredFFT              matlab.ui.control.UIAxes
        DiagramUnfilteredFFT            matlab.ui.control.UIAxes
        DiagramFiltered                 matlab.ui.control.UIAxes
        DiagramUnfiltered               matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        SearchButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        FilepathLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% Center Frequencies
        F1 = 31.5;
        StdF1 = 31.5;
        F2 = 63;
        StdF2 = 63;
        F3 = 125;
        StdF3 = 125;
        F4 = 250;
        StdF4 = 250;
        F5 = 500;
        StdF5 = 500;
        F6 = 1000;
        StdF6 = 1000;
        F7 = 2000;
        StdF7 = 2000;
        F8 = 4000;
        StdF8 = 4000;
        F9 = 8000;
        StdF9 = 8000;
        F10 = 16000;
        StdF10 = 16000;
        
        F1Noise= 31.5;
        F2Noise= 63;
        F3Noise= 125;
        F4Noise= 250;
        F5Noise= 500;
        F6Noise= 1000;
        F7Noise= 2000;
        F8Noise= 4000;
        F9Noise= 8000;
        F10Noise = 18000;
        FVolume = 10000;
        FNoiseArray;

        %% Bandwidths
        Bandwidth1 = 25;
        StdBw1 = 25;
        Bandwidth2 = 42;
        StdBw2 = 42;
        Bandwidth3 = 80;
        StdBw3 = 80;
        Bandwidth4= 170;
        StdBw4 = 170;
        Bandwidth5 = 330;
        StdBw5 = 330;
        Bandwidth6 = 550;
        StdBw6 = 550;
        Bandwidth7 = 1350;
        StdBw7 = 1350;
        Bandwidth8 = 2000;
        StdBw8 = 2000;
        Bandwidth9 = 5000;
        StdBw9 = 5000;
        Bandwidth10 = 7000;
        StdBw10 = 7000;

        Bandwidth1Noise = 20;
        Bandwidth2Noise = 32;
        Bandwidth3Noise = 80;
        Bandwidth4Noise= 170;
        Bandwidth5Noise = 330;
        Bandwidth6Noise = 550;
        Bandwidth7Noise = 1350;
        Bandwidth8Noise = 2000;
        Bandwidth9Noise = 5000;
        Bandwidth10Noise = 8000;
        BandwidthVolume = 50000;
        BwNoiseArray

        %% Gains
        GainVolume = 0;
        BandGainArray;
        %% Quality Factors
        N1 = 4;
        N2 = 4;
        N3 = 4;
        N4 = 4;
        N5 = 4;
        N6 = 4;
        N7 = 4;
        N8 = 4;
        N9 = 4;
        N10 = 4;
        NNoiseComparisonFilter = 80;
        NVolume = 2;
        StdN = 4;
        %% UI Parameters
        MinGain = -20; % Control for slider values
        MaxGain = 20; % Control for slider values
        audioOut;
        playNow;
        screenHeight;
        screenWidth;
        BandSlidersArray;
        BandEditsArray;
        %% Data Parameters
        Fs = 0; % Sample rate, starts 0 for verification if data has already been loaded
        y; % Raw data
        k; % sample number
        B; % Filter denominators
        A; % Filter numerators
        f;
        F;
        Y;
        filtered_y; % Filtered data
        filtered_Y
        %%  Noise Parameters
        noise_Fs = 0;
        noise_y;
        noise_k;
        noise_B;
        noise_A;
        noise_f;
        noise_F;
        noise_Y
        filtered_noise_y;
        filtered_noise_Y;
    end
    methods (Access = private)
        
        function DoubleVal = RemoveNonNumeric(~,text)
            DoubleVal = strrep(text,",",".");
            DoubleVal = regexprep(DoubleVal, '[^\d-.]', '');
        end
        
        function limitOverload = checkSliderLimit(~, input, minVal, maxVal)
            if input > maxVal
                limitOverload = maxVal;
            elseif input < minVal
                limitOverload = minVal;
            else
                limitOverload = input;
            end
        end
        

        function updatePEQ(app)
            [Wo, N, G, Bw] = readParams(app); % update parameters
            
            
            [app.B, app.A] = designParamEQ(N,G,Wo,Bw,'Orientation','row'); % Update EQ
            
            performFiltering(app); % Filter
            updateFiltDiagrams(app); % Update Diagrams
        end

        function updatePEQNoise(app)
            %% update parameters            
            selectedBand = checkSelectedButton(app);
            
            Wo = [app.FNoiseArray(selectedBand), app.FNoiseArray(11)]/(app.noise_Fs/2);
            Bw = [app.BwNoiseArray(selectedBand), app.BwNoiseArray(11)]/(app.noise_Fs/2);
            N = [app.NNoiseComparisonFilter app.NVolume];   
            G = [app.Slider.Value+80 -80];

            %% Update EQ
            [app.noise_B, app.noise_A] = designParamEQ(N,G,Wo,Bw,'Orientation','row');
            performFilteringNoise(app);
            updateFiltDiagramsNoise(app);
        end

        function [Wo, N, G, Bw] = readParams(app)
            % collect and normalize Center Frequency and Bandwidth to
            % Nyquist Frequency

            app.F1 = app.SpnF1.Value;
            app.Bandwidth1 = app.SpnBw1.Value;
            app.N1 = app.SpnN1.Value;

            app.F2 = app.SpnF2.Value;
            app.Bandwidth2 = app.SpnBw2.Value;
            app.N2 = app.SpnN2.Value;

            app.F3 = app.SpnF3.Value;
            app.Bandwidth3 = app.SpnBw3.Value;
            app.N3 = app.SpnN3.Value;

            app.F4 = app.SpnF4.Value;
            app.Bandwidth4 = app.SpnBw4.Value;
            app.N4 = app.SpnN4.Value;

            app.F5 = app.SpnF5.Value;
            app.Bandwidth5 = app.SpnBw5.Value;
            app.N5 = app.SpnN5.Value;

            app.F6 = app.SpnF6.Value;
            app.Bandwidth6 = app.SpnBw6.Value;
            app.N6 = app.SpnN6.Value;

            app.F7 = app.SpnF7.Value;
            app.Bandwidth7 = app.SpnBw7.Value;
            app.N7 = app.SpnN7.Value;

            app.F8 = app.SpnF8.Value;
            app.Bandwidth8 = app.SpnBw8.Value;
            app.N8 = app.SpnN8.Value;

            app.F9 = app.SpnF9.Value;
            app.Bandwidth9 = app.SpnBw9.Value;
            app.N9 = app.SpnN9.Value;

            app.F10 = app.SpnF10.Value;
            app.Bandwidth10 = app.SpnBw10.Value;
            app.N10 = app.SpnN10.Value;

            app.GainVolume = app.SliderVolume.Value/100;
            app.GainVolume = 20*log10(app.GainVolume);

            Wo = [app.F1 app.F2 app.F3 app.F4 app.F5 app.F6 app.F7 app.F8 app.F9 app.F10 app.FVolume]/(app.Fs/2);
            G = [app.Sliderf1.Value app.Sliderf2.Value app.Sliderf3.Value app.Sliderf4.Value app.Sliderf5.Value app.Sliderf6.Value app.Sliderf7.Value app.Sliderf8.Value app.Sliderf9.Value app.Sliderf10.Value app.GainVolume];
            N = [app.N1 app.N2 app.N3 app.N4 app.N5 app.N6 app.N7 app.N8 app.N9 app.N10 app.NVolume];
            Bw = [app.Bandwidth1 app.Bandwidth2 app.Bandwidth3 app.Bandwidth4 app.Bandwidth5 app.Bandwidth6 app.Bandwidth7 app.Bandwidth8 app.Bandwidth9 app.Bandwidth10 app.BandwidthVolume]/(app.Fs/2);
            
            updateLabels(app);
        
        end


        function disableAll(app)
            tabs = app.TabGroup.Children;
            for i = 1:numel(tabs)
                children = tabs(i).Children;
                for j = 1:numel(children)
                    if children(j).Type ~= "axes"
                        children(j).Enable = "off";
                    end
                end
            end
        end


        function enableAll(app)
            tabs = app.TabGroup.Children;
            for i = 1:numel(tabs)
                children = tabs(i).Children;
                for j = 1:numel(children)
                    if children(j).Type ~= "axes"
                        children(j).Enable = "on";
                    end
                end
            end
        end
        
        function startComponents(app)
            app.SpnF1.Value = app.StdF1;
            app.SpnBw1.Value = app.StdBw1;
            app.SpnN1.Value = app.StdN;

            app.SpnF2.Value = app.StdF2;
            app.SpnBw2.Value = app.StdBw2;
            app.SpnN2.Value = app.StdN;

            app.SpnF3.Value = app.StdF3;
            app.SpnBw3.Value = app.StdBw3;
            app.SpnN3.Value = app.StdN;

            app.SpnF4.Value = app.StdF4;
            app.SpnBw4.Value = app.StdBw4;
            app.SpnN4.Value = app.StdN;

            app.SpnF5.Value = app.StdF5;
            app.SpnBw5.Value = app.StdBw5;
            app.SpnN5.Value = app.StdN;

            app.SpnF6.Value = app.StdF6;
            app.SpnBw6.Value = app.StdBw6;
            app.SpnN6.Value = app.StdN;

            app.SpnF7.Value = app.StdF7;
            app.SpnBw7.Value = app.StdBw7;
            app.SpnN7.Value = app.StdN;

            app.SpnF8.Value = app.StdF8;
            app.SpnBw8.Value = app.StdBw8;
            app.SpnN8.Value = app.StdN;

            app.SpnF9.Value = app.StdF9;
            app.SpnBw9.Value = app.StdBw9;
            app.SpnN9.Value = app.StdN;

            app.SpnF10.Value = app.StdF10;
            app.SpnBw10.Value = app.StdBw10;
            app.SpnN10.Value = app.StdN;
        end
        
        function updateLabels(app)
            app.Panelf1.Title = (app.F1 - app.Bandwidth1) + " - " + (app.F1 + app.Bandwidth1);
            app.Panelf2.Title = (app.F2 - app.Bandwidth2) + " - " + (app.F2 + app.Bandwidth2);
            app.Panelf3.Title = (app.F3 - app.Bandwidth3) + " - " + (app.F3 + app.Bandwidth3);
            app.Panelf4.Title = (app.F4 - app.Bandwidth4) + " - " + (app.F4 + app.Bandwidth4);
            app.Panelf5.Title = (app.F5 - app.Bandwidth5) + " - " + (app.F5 + app.Bandwidth5);
            app.Panelf6.Title = (app.F6 - app.Bandwidth6) + " - " + (app.F6 + app.Bandwidth6);
            app.Panelf7.Title = (app.F7 - app.Bandwidth7) + " - " + (app.F7 + app.Bandwidth7);
            app.Panelf8.Title = (app.F8 - app.Bandwidth8) + " - " + (app.F8 + app.Bandwidth8);
            app.Panelf9.Title = (app.F9 - app.Bandwidth9) + " - " + (app.F9 + app.Bandwidth9);
            app.Panelf10.Title = app.F10 - app.Bandwidth10 + " - " + app.F10 + app.Bandwidth10;
        end
        
        function playSound(app, y, Fs)
            app.audioOut = audioDeviceWriter('SampleRate', Fs);
            app.audioOut.SupportVariableSizeInput = true;
            % Create the equalizer
            % Divide the signal into frames for real-time processing
            frameLength = 2048;
            numFrames = ceil(length(y) / frameLength);
            % Play audio in a loop
            idx = 1;
            app.playNow = true;
            while idx <= numFrames && app.playNow
               % Get current frame
               frameStart = (idx - 1) * frameLength + 1;
               frameEnd = min(idx * frameLength, length(y));
               audioFrame = y(frameStart:frameEnd, :);
               
               app.audioOut(audioFrame);
               drawnow;
                       idx = idx + 1;
            end
            % Release the audio writer after playback
            release(app.audioOut);
        end
        
        function updateRawDiagrams(app)
            plot(app.DiagramUnfiltered,app.k, app.y);
            xlabel(app.DiagramUnfiltered, "Sample");
            ylabel(app.DiagramUnfiltered, "Amplitude")
            title(app.DiagramUnfiltered, 'Unfiltered Data');

            app.Y = fft(app.y);
            N = length(app.y);
            app.Y = app.Y(1:round(N/2)+1);
            F_RAW = app.Fs*(0:(round(N/2)))/N;
            semilogx(app.DiagramUnfilteredFFT, F_RAW, 20*log10(abs(app.Y)));
            xlim(app.DiagramUnfilteredFFT, [20 22050]);
            xlabel(app.DiagramUnfilteredFFT, 'Frequency [Hz]');
            ylabel(app.DiagramUnfilteredFFT, 'Gain [dB]');
            title(app.DiagramUnfilteredFFT, 'Unfiltered FFT');
            grid(app.DiagramUnfilteredFFT, "on")
        end
        
        function updateFiltDiagrams(app)
            [h, w] = freqz(app.B, app.A, 22030, app.Fs); 
            semilogx(app.DiagramTF, w, 20*log10(abs(h))); 
            grid(app.DiagramTF, "on"); 
            xlim(app.DiagramTF, [20 22050]); 
            ylim(app.DiagramTF, [-20.2 20.2]); 
            xlabel(app.DiagramTF, 'Frequency (Hz)'); 
            ylabel(app.DiagramTF, 'Magnitude (dB)'); 
            title(app.DiagramTF, 'Bode Plot - Magnitude Response');

            app.filtered_Y = fft(app.filtered_y);
            N = length(app.filtered_y);
            app.filtered_Y = app.filtered_Y(1:round(N/2)+1);
            F_Filtered = app.Fs*(0:(round(N/2)))/N;

            plot(app.DiagramFiltered,app.k, app.filtered_y);
            xlabel(app.DiagramFiltered, "Sample");
            ylabel(app.DiagramFiltered, "Amplitude")
            title(app.DiagramFiltered, 'Filtered Data');

            semilogx(app.DiagramFilteredFFT, F_Filtered, 20*log10(abs(app.filtered_Y)));
            xlim(app.DiagramFilteredFFT, [20 22050]);
            xlabel(app.DiagramFilteredFFT, 'Frequency [Hz]');
            ylabel(app.DiagramFilteredFFT, 'Gain [dB]');
            title(app.DiagramFilteredFFT, 'Filtered FFT');
            grid(app.DiagramFilteredFFT, "on"); 
        end
        
        function updateRawDiagramsNoise(app)
            plot(app.DiagramUnfilteredNoise,app.noise_k, app.noise_y);
            xlabel(app.DiagramUnfilteredNoise, "Sample");
            ylabel(app.DiagramUnfilteredNoise, "Amplitude")
            title(app.DiagramUnfilteredNoise, 'Unfiltered Data');

            app.noise_Y = fft(app.noise_y);
            N = length(app.noise_y);
            app.noise_Y = app.noise_Y(1:round(N/2)+1);
            F_RAW = app.noise_Fs*(0:(round(N/2)))/N;
            semilogx(app.DiagramUnfilteredFFTNoise, F_RAW, 20*log10(abs(app.noise_Y)));
            xlim(app.DiagramUnfilteredFFTNoise, [20 22050]);
            xlabel(app.DiagramUnfilteredFFTNoise, 'Frequency [Hz]');
            ylabel(app.DiagramUnfilteredFFTNoise, 'Gain [dB]');
            title(app.DiagramUnfilteredFFTNoise, 'Unfiltered FFT');
            grid(app.DiagramUnfilteredFFTNoise, "on")
        end
        
        function updateFiltDiagramsNoise(app)
            [h, w] = freqz(app.noise_B, app.noise_A, 22030, app.noise_Fs); 
            semilogx(app.DiagramTF_Noise, w, 20*log10(abs(h))); 
            grid(app.DiagramTF_Noise, "on"); 
            xlim(app.DiagramTF_Noise, [20 22050]); 
            ylim(app.DiagramTF_Noise, [-20.2 20.2]); 
            xlabel(app.DiagramTF_Noise, 'Frequency (Hz)'); 
            ylabel(app.DiagramTF_Noise, 'Magnitude (dB)'); 
            title(app.DiagramTF_Noise, 'Bode Plot - Magnitude Response');

            app.filtered_noise_Y = fft(app.filtered_noise_y);
            N = length(app.filtered_noise_y);
            app.filtered_noise_Y = app.filtered_noise_Y(1:round(N/2)+1);
            F_Filtered = app.noise_Fs*(0:(round(N/2)))/N;

            plot(app.DiagramFilteredNoise,app.noise_k, app.filtered_noise_y);
            xlabel(app.DiagramFilteredNoise, "Sample");
            ylabel(app.DiagramFilteredNoise, "Amplitude")
            title(app.DiagramFilteredNoise, 'Filtered Data');

            semilogx(app.DiagramFilteredFFTNoise, F_Filtered, 20*log10(abs(app.filtered_noise_Y)));
            xlim(app.DiagramFilteredFFTNoise, [20 22050]);
            xlabel(app.DiagramFilteredFFTNoise, 'Frequency [Hz]');
            ylabel(app.DiagramFilteredFFTNoise, 'Gain [dB]');
            title(app.DiagramFilteredFFTNoise, 'Filtered FFT');
            grid(app.DiagramFilteredFFTNoise, "on"); 
        end

        function updateVolumeDataDiagram(app)
            plot(app.DiagramUnfilteredNoiseVolume,app.noise_k, app.noise_y);
            xlabel(app.DiagramUnfilteredNoiseVolume, "Sample");
            ylabel(app.DiagramUnfilteredNoiseVolume, "Amplitude")
            title(app.DiagramUnfilteredNoiseVolume, 'Unfiltered Data');

            app.noise_Y = fft(app.noise_y);
            N = length(app.noise_y);
            app.noise_Y = app.noise_Y(1:round(N/2)+1);
            F_RAW = app.noise_Fs*(0:(round(N/2)))/N;
            semilogx(app.DiagramUnfilteredFFTVolume, F_RAW, 20*log10(abs(app.noise_Y)));
            xlim(app.DiagramUnfilteredFFTVolume, [20 22050]);
            xlabel(app.DiagramUnfilteredFFTVolume, 'Frequency [Hz]');
            ylabel(app.DiagramUnfilteredFFTVolume, 'Gain [dB]');
            title(app.DiagramUnfilteredFFTVolume, 'Unfiltered FFT');
            grid(app.DiagramUnfilteredFFTVolume, "on")
        end
        
        function updateVolumeFiltDataDiagram(app)
            [h, w] = freqz(app.noise_B, app.noise_A, 22030, app.noise_Fs); 
            semilogx(app.DiagramTF_Volume, w, 20*log10(abs(h))); 
            grid(app.DiagramTF_Volume, "on"); 
            xlim(app.DiagramTF_Volume, [20 22050]); 
            ylim(app.DiagramTF_Volume, [-20.2 20.2]); 
            xlabel(app.DiagramTF_Volume, 'Frequency (Hz)'); 
            ylabel(app.DiagramTF_Volume, 'Magnitude (dB)'); 
            title(app.DiagramTF_Volume, 'Bode Plot - Magnitude Response');

            app.filtered_noise_Y = fft(app.filtered_noise_y);
            N = length(app.filtered_noise_y);
            app.filtered_noise_Y = app.filtered_noise_Y(1:round(N/2)+1);
            F_Filtered = app.noise_Fs*(0:(round(N/2)))/N;

            plot(app.DiagramFilteredNoiseVolume,app.noise_k, app.filtered_noise_y);
            xlabel(app.DiagramFilteredNoiseVolume, "Sample");
            ylabel(app.DiagramFilteredNoiseVolume, "Amplitude")
            title(app.DiagramFilteredNoiseVolume, 'Filtered Data');

            semilogx(app.DiagramFilteredFFTVolume, F_Filtered, 20*log10(abs(app.filtered_noise_Y)));
            xlim(app.DiagramFilteredFFTVolume, [20 22050]);
            xlabel(app.DiagramFilteredFFTVolume, 'Frequency [Hz]');
            ylabel(app.DiagramFilteredFFTVolume, 'Gain [dB]');
            title(app.DiagramFilteredFFTVolume, 'Filtered FFT');
            grid(app.DiagramFilteredFFTVolume, "on"); 
        end

        function performFiltering(app)
            clear app.filtered_Y;
            clear app.filtered_y;
            clear N;
            clear app.F;

            rows = size(app.A);
            rows = rows(1);
            app.filtered_y = app.y;
            for section = 1:rows
                app.filtered_y = filter(app.B(section,:),app.A(section,:), app.filtered_y);
            end
        end

        function performFilteringNoise(app)
            clear app.filtered_noise_Y;
            clear app.filtered_noise_y;
            clear N;
            clear app.noise_F;

            rows = size(app.noise_A);
            rows = rows(1);
            app.filtered_noise_y = app.noise_y;
            for section = 1:rows
                app.filtered_noise_y = filter(app.noise_B(section,:),app.noise_A(section,:), app.filtered_noise_y);
            end
        end
        
        function index = checkSelectedButton(app)
            if app.ButtonGroup.SelectedObject == app.Band1Button
                index = 1;
            elseif app.ButtonGroup.SelectedObject == app.Band2Button
                index = 2;
            elseif app.ButtonGroup.SelectedObject == app.Band3Button
                index = 3;
            elseif app.ButtonGroup.SelectedObject == app.Band4Button
                index = 4;
            elseif app.ButtonGroup.SelectedObject == app.Band5Button
                index = 5;
            elseif app.ButtonGroup.SelectedObject == app.Band6Button
                index = 6;
            elseif app.ButtonGroup.SelectedObject == app.Band7Button
                index = 7;
            elseif app.ButtonGroup.SelectedObject == app.Band8Button
                index = 8;
            elseif app.ButtonGroup.SelectedObject == app.Band9Button
                index = 9;
            else
                index = 10;
            end
        end
        
        function noiseData = genNoise(app, time)
            if app.NoisemodeButtonGroup.SelectedObject == app.RosanoiseButton
                noiseData = pinknoise(app.noise_Fs*time);
            else
                noiseData = randn([app.noise_Fs*time, 1], "double");
                noiseData = max(min(noiseData, 0.5), -0.5);
            end
            
        end
        
        function filterForVolume(app, linearVolumeOffset)
            volumeOffsetdB = 20*log10(linearVolumeOffset);
            if (app.NoisemodeButtonGroup.SelectedObject == app.AWeightedButton)
                AWeighting = weightingFilter('A-weighting', app.noise_Fs);
                coeffs = AWeighting.coeffs;
                sossWeighted = coeffs.SOSMatrix;
                scaleValues = coeffs.ScaleValues;
                
                for i = 1:size(sossWeighted, 1) 
                    sossWeighted(i, 1:3) = sossWeighted(i, 1:3) * scaleValues(i); 
                end
                overallScale = scaleValues(end);
                sossWeighted = sossWeighted * overallScale;
                AScalingNumerators = sossWeighted(:,1:3);
                AScalingDenominators = sossWeighted(:,4:end);
                
                % 1 band for volume adjusting
                A_Freq = app.FVolume/(app.noise_Fs/2);
                A_Gain = volumeOffsetdB;
                A_Band = app.BandwidthVolume/(app.noise_Fs/2);
                A_Orde = 2;
                
                % Create the A-weighted filter using designParamEQ
                [app.noise_B, app.noise_A] = designParamEQ(A_Orde, A_Gain, A_Freq, A_Band,'Orientation','row');

                % append the A-Filter to the volume
                app.noise_A = [app.noise_A; AScalingDenominators];
                app.noise_B = [app.noise_B; AScalingNumerators];
                
            else % White noise and pink noise. Since they area already withe/rosa noise, only volume matters
                A_Freq = app.FVolume/(app.noise_Fs/2); % Example frequencies
                A_Gain = volumeOffsetdB; 
                A_Band = app.BandwidthVolume/(app.noise_Fs/2);
                A_Orde = 2;
                [app.noise_B, app.noise_A] = designParamEQ(A_Orde, A_Gain, A_Freq, A_Band,'Orientation','row');
            end
            performFilteringNoise(app);
        end
        
        function sendUDPArrayVariable(~, array, destIP, destPort)
            % SENDUDPARRAYVARIABLE Sends a variable-sized array over UDP
            % Inputs:
            %   array   - Numeric array to send
            %   destIP  - Destination IP address (e.g., '127.0.0.1' for localhost)
            %   destPort- Destination UDP port (e.g., 5000)
            
            % Create a UDP object
            udpSender = udpport('Datagram', 'IPV4');
            
            fprintf("Array size: ")
            size(array)

            % Flatten the array and cast to uint8
            dataBytes = typecast(array(:), 'uint8'); 
            
            % Include the size of the array as a prefix
            sizeBytes = typecast(uint32(size(array)), 'uint8'); % Encode size as uint32
            sizeBytes = sizeBytes';
            
            % Combine size and data bytes
            message = [sizeBytes; dataBytes];
            
            % Send the datagram
            write(udpSender, message, 'uint8', destIP, destPort);
            
            % Display status
            fprintf('Array of size [%s] sent to %s:%d\n', num2str(size(array)), destIP, destPort);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BwNoiseArray = [app.Bandwidth1Noise app.Bandwidth2Noise app.Bandwidth3Noise app.Bandwidth4Noise app.Bandwidth5Noise app.Bandwidth6Noise app.Bandwidth7Noise app.Bandwidth8Noise app.Bandwidth9Noise app.Bandwidth10Noise app.BandwidthVolume];
            app.FNoiseArray = [app.F1Noise app.F2Noise app.F3Noise app.F4Noise app.F5Noise app.F6Noise app.F7Noise app.F8Noise app.F9Noise app.F10Noise app.FVolume];
            app.BandSlidersArray = [app.Sliderf1 app.Sliderf2 app.Sliderf3 app.Sliderf4 app.Sliderf5 app.Sliderf6 app.Sliderf7 app.Sliderf8 app.Sliderf9 app.Sliderf10];
            app.BandEditsArray = [app.Editf1 app.Editf2 app.Editf3 app.Editf4 app.Editf5 app.Editf6 app.Editf7 app.Editf8 app.Editf9 app.Editf10];

           %% Position window
            screenInfo = get(0, 'ScreenSize'); 
            app.screenWidth = screenInfo(3);
            app.screenHeight = screenInfo(4);
            app.UIFigure.Position = [0, app.screenHeight - app.UIFigure.Position(4)-30, app.UIFigure.Position(3), app.UIFigure.Position(4)];
          
           %% Adjust Fields
            startComponents(app);
            updateLabels(app);
            disableAll(app);
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            [filename, pathname] = uigetfile("*.wav", "File Selector");
            app.Label.Text = strcat(pathname,filename);
            [app.y, app.Fs] = audioread(strcat(pathname,filename));
            app.k = (0:length(app.y)-1) / app.Fs;
           
            updateRawDiagrams(app);
            updatePEQ(app);
            enableAll(app);
        end

        % Value changed function: Sliderf1
        function Sliderf1_onChange(app, event)
            value = app.Sliderf1.Value;
            app.Editf1.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf2
        function Sliderf2_onChange(app, event)
            value = app.Sliderf2.Value;
            app.Editf2.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf3
        function Sliderf3_onChange(app, event)
            value = app.Sliderf3.Value;
            app.Editf3.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf4
        function Sliderf4_onChange(app, event)
            value = app.Sliderf4.Value;
            app.Editf4.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf5
        function Sliderf5_onChange(app, event)
            value = app.Sliderf5.Value;
            app.Editf5.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf6
        function Sliderf6_onChange(app, event)
            value = app.Sliderf6.Value;
            app.Editf6.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf7
        function Sliderf7_onChange(app, event)
            value = app.Sliderf7.Value;
            app.Editf7.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf8
        function Sliderf8_onChange(app, event)
            value = app.Sliderf8.Value;
            app.Editf8.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf9
        function Sliderf9_onChange(app, event)
            value = app.Sliderf9.Value;
            app.Editf9.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Sliderf10
        function Sliderf10_onChange(app, event)
            value = app.Sliderf10.Value;
            app.Editf10.Value = num2str(value,2);
            app.updatePEQ();
        end

        % Value changed function: Editf1
        function Editf1_onChange(app, event)
            value = app.Editf1.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf1.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf1.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf2
        function Editf2_onChange(app, event)
            value = app.Editf2.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf2.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf2.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf3
        function Editf3_onChange(app, event)
            value = app.Editf3.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf3.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf3.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf4
        function Editf4_onChange(app, event)
            value = app.Editf4.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf4.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf4.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf5
        function Editf5_onChange(app, event)
            value = app.Editf5.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf5.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf5.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf6
        function Editf6_onChange(app, event)
            value = app.Editf6.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf6.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf6.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf7
        function Editf7_onChange(app, event)
            value = app.Editf7.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf7.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf7.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf8
        function Editf8_onChange(app, event)
            value = app.Editf8.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf8.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf8.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf9
        function Editf9_onChange(app, event)
            value = app.Editf9.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf9.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf9.Value = value;
            app.updatePEQ();
        end

        % Value changed function: Editf10
        function Editf10_onChange(app, event)
            value = app.Editf10.Value;
            value = str2double(RemoveNonNumeric(app, value));
            minMax = app.Sliderf10.Limits;
            minVal = minMax(1);
            maxVal = minMax(2);
            value = checkSliderLimit(app, value, minVal, maxVal);
            app.Sliderf10.Value = value;
            app.updatePEQ();
        end

        % Button pushed function: ResetFButton
        function ResetFButtonPushed(app, event)
            app.F1 = app.StdF1;
            app.SpnF1.Value = app.F1;

            app.F2 = app.StdF2;
            app.SpnF2.Value = app.F2;

            app.F3 = app.StdF3;
            app.SpnF3.Value = app.F3;

            app.F4 = app.StdF4;
            app.SpnF4.Value = app.F4;

            app.F5 = app.StdF5;
            app.SpnF5.Value = app.F5;

            app.F6 = app.StdF6;
            app.SpnF6.Value = app.F6;

            app.F7 = app.StdF7;
            app.SpnF7.Value = app.F7;

            app.F8 = app.StdF8;
            app.SpnF8.Value = app.F8;

            app.F9 = app.StdF9;
            app.SpnF9.Value = app.F9;

            app.F10 = app.StdF10;
            app.SpnF10.Value = app.F10;

            app.updatePEQ();
        end

        % Button pushed function: ResetOrderButton
        function ResetOrderButtonPushed(app, event)
            app.SpnN1.Value = app.StdN;
            app.SpnN2.Value = app.StdN;
            app.SpnN3.Value = app.StdN;
            app.SpnN4.Value = app.StdN;
            app.SpnN5.Value = app.StdN;
            app.SpnN6.Value = app.StdN;
            app.SpnN7.Value = app.StdN;
            app.SpnN8.Value = app.StdN;
            app.SpnN9.Value = app.StdN;
            app.SpnN10.Value = app.StdN;

            app.N1 = app.StdN;
            app.N2 = app.StdN;
            app.N3 = app.StdN;
            app.N4 = app.StdN;
            app.N5 = app.StdN;
            app.N6 = app.StdN;
            app.N7 = app.StdN;
            app.N8 = app.StdN;
            app.N9 = app.StdN;
            app.N10 = app.StdN;

            app.updatePEQ();
        end

        % Button pushed function: ResetGainsButton
        function ResetGainsButtonPushed(app, event)
            app.Sliderf1.Value = 0;
            app.Sliderf2.Value = 0;
            app.Sliderf3.Value = 0;
            app.Sliderf4.Value = 0;
            app.Sliderf5.Value = 0;
            app.Sliderf6.Value = 0;
            app.Sliderf7.Value = 0;
            app.Sliderf8.Value = 0;
            app.Sliderf9.Value = 0;
            app.Sliderf10.Value = 0;

            app.Editf1.Value = "0";
            app.Editf2.Value = "0";
            app.Editf3.Value = "0";
            app.Editf4.Value = "0";
            app.Editf5.Value = "0";
            app.Editf6.Value = "0";
            app.Editf7.Value = "0";
            app.Editf8.Value = "0";
            app.Editf9.Value = "0";
            app.Editf10.Value = "0";

            app.updatePEQ();
        end

        % Button pushed function: BtnPlay
        function BtnPlayButtonPushed(app, event)
            app.BtnPlay.Enable = "off";
            app.BtnPlay.Text = "Playing";

            playSound(app, app.filtered_y, app.Fs);

            app.BtnPlay.Enable = "on";
            app.BtnPlay.Text = "Play";
        end

        % Button pushed function: BtnStop
        function BtnStopButtonPushed(app, event)
            app.playNow = false;
            release(app.audioOut);

            app.BtnPlay.Enable = "on";
            app.BtnPlay.Text = "Play";
        end

        % Value changed function: SpnBw1, SpnBw10, SpnBw2, SpnBw3, SpnBw4, 
        % ...and 25 other components
        function CallbackUpdatepEQ(app, event)
            updatePEQ(app);
        end

        % Button pushed function: BtnResetBw
        function BtnResetBwButtonPushed(app, event)
            app.SpnBw1.Value = app.StdBw1;
            app.Bandwidth1 = app.StdBw1;

            app.SpnBw2.Value = app.StdBw2;
            app.Bandwidth2 = app.StdBw2;

            app.SpnBw3.Value = app.StdBw3;
            app.Bandwidth3 = app.StdBw3;

            app.SpnBw4.Value = app.StdBw4;
            app.Bandwidth4 = app.StdBw4;

            app.SpnBw5.Value = app.StdBw5;
            app.Bandwidth5 = app.StdBw5;

            app.SpnBw6.Value = app.StdBw6;
            app.Bandwidth6 = app.StdBw6;

            app.SpnBw7.Value = app.StdBw7;
            app.Bandwidth7 = app.StdBw7;

            app.SpnBw8.Value = app.StdBw8;
            app.Bandwidth8 = app.StdBw8;

            app.SpnBw9.Value = app.StdBw9;
            app.Bandwidth9 = app.StdBw9;

            app.SpnBw10.Value = app.StdBw10;
            app.Bandwidth10 = app.StdBw10;

            updatePEQ(app);
        end

        % Button pushed function: BtnSave
        function BtnSaveButtonPushed(app, event)
            mEQ_B = app.B;
            mEQ_A = app.A;
            save EQ_Filter mEQ_B mEQ_A;

            sosCoefficients = [app.B app.A];
            sendUDPArrayVariable(app, sosCoefficients,"127.0.0.1", 5000);
            %sendUDPArray(app, sosCoefficients, "127.0.0.1", 5000);
            %save("sosCoefficients.mat", "sosCoefficients");
        end

        % Button pushed function: BtnGenNoise
        function BtnGenNoisePushed(app, event)
            time = str2double(app.SignaldurationsEditField.Value);
            app.noise_Fs = str2double(app.SampleFrequency1sEditField.Value);
            app.noise_y = genNoise(app, time);
            app.noise_k = 1:length(app.noise_y);
            updateRawDiagramsNoise(app);
            updatePEQNoise(app); % important here is the second param
        end

        % Button pushed function: BtnPlayNoise
        function BtnPlayNoiseButtonPushed(app, event)
            app.BtnPlayNoise.Enable = "off";
            app.BtnPlayNoise.Text = "Playing";

            playSound(app, app.filtered_noise_y, app.noise_Fs);

            app.BtnPlayNoise.Enable = "on";
            app.BtnPlayNoise.Text = "Play";
        end

        % Button pushed function: BtnStopNoise
        function BtnStopNoiseButtonPushed(app, event)
            app.playNow = false;
            release(app.audioOut);

            app.BtnPlayNoise.Enable = "on";
            app.BtnPlayNoise.Text = "Play";
        end

        % Button pushed function: BtnSaveBandGain
        function BtnSaveBandGainButtonPushed(app, event)
            selectedBand = checkSelectedButton(app);
            app.BandSlidersArray(selectedBand).Value = app.Slider.Value;
            app.BandEditsArray(selectedBand).Value = num2str(app.Slider.Value);
            drawnow;
            updatePEQ(app);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            time = 60; % 1 minute
            app.noise_Fs = str2double(app.SampleFrequencyVolumeEdit.Value);
            app.noise_y = genNoise(app, time);
            app.noise_k = 1:length(app.noise_y);
            filterForVolume(app, app.SliderVolume.Value/100);
            
            updateVolumeDataDiagram(app);
            updateVolumeFiltDataDiagram(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.PlayButton.Enable = "off";
            app.PlayButton.Text = "Playing";

            playSound(app, app.filtered_noise_y, app.noise_Fs);

            app.PlayButton.Enable = "on";
            app.PlayButton.Text = "Play";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.playNow = false;
            release(app.audioOut);

            app.PlayButton.Enable = "on";
            app.PlayButton.Text = "Play";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.GainVolume = 20*log10(app.SliderVolume.Value/100);
            updatePEQ(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.FilepathLabel = uilabel(app.Panel);
            app.FilepathLabel.Text = 'File path';
            app.Label = uilabel(app.Panel);
            app.Label.Tag = 'lbl_Path';
            app.Label.Text = '';
            app.SearchButton = uibutton(app.Panel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Tag = 'btn_Search';
            app.SearchButton.Text = 'Search';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralvolumecomparisonTab = uitab(app.TabGroup);
            app.GeneralvolumecomparisonTab.Title = 'General volume comparison';
            app.DiagramTF_Volume = uiaxes(app.GeneralvolumecomparisonTab);
            title(app.DiagramTF_Volume, 'Transfer Function')
            app.DiagramUnfilteredNoiseVolume = uiaxes(app.GeneralvolumecomparisonTab);
            title(app.DiagramUnfilteredNoiseVolume, 'Unfiltered Data')
            app.DiagramFilteredNoiseVolume = uiaxes(app.GeneralvolumecomparisonTab);
            title(app.DiagramFilteredNoiseVolume, 'Filtered Data')
            app.DiagramUnfilteredFFTVolume = uiaxes(app.GeneralvolumecomparisonTab);
            title(app.DiagramUnfilteredFFTVolume, 'Unfiltered FFT')
            app.DiagramFilteredFFTVolume = uiaxes(app.GeneralvolumecomparisonTab);
            title(app.DiagramFilteredFFTVolume, 'Filtered FFT')
            app.VolumeLabel = uilabel(app.GeneralvolumecomparisonTab);
            app.VolumeLabel.Text = 'Volume (%)';
            app.SliderVolume = uislider(app.GeneralvolumecomparisonTab);
            app.SliderVolume.Limits = [0.1 200];
            app.SliderVolume.MajorTicks = [0.1 20 40 60 80 100 120 140 160 180 200];
            app.SliderVolume.MinorTicks = [0.1 2 4 6 8.1 10.1 12.1 14.1 16.1 18.1 20.1 22.1 24.1 26.1 28.1 30.1 32.1 34.1 36.1 38.1 40.1 42.1 44.1 46.1 48.1 50.1 52.1 54.1 56.1 58.1 60.1 62.1 64.1 66.1 68.1 70.1 72.1 74.1 76.1 78.1 80.1 82.1 84.1 86.1 88.1 90.1 92.1 94.1 96.1 98.1 100.1 102.1 104.1 106.1 108.1 110.1 112.1 114.1 116.1 118.1 120.1 122.1 124.1 126.1 128.1 130.1 132.1 134.1 136.1 138.1 140.1 142.1 144.1 146.1 148.1 150.1 152.1 154.1 156.1 158.1 160.1 162.1 164.1 166.1 168.1 170.1 172.1 174.1 176.1 178.1 180.1 182.1 184.1 186.1 188.1 190.1 192.1 194.1 196.1 198.1 200];
            app.SliderVolume.Value = 100;
            app.NoisemodeButtonGroup = uibuttongroup(app.GeneralvolumecomparisonTab);
            app.NoisemodeButtonGroup.Title = 'Noise mode';
            app.AWeightedButton = uitogglebutton(app.NoisemodeButtonGroup);
            app.AWeightedButton.Text = 'A-Weighted';
            app.AWeightedButton.Value = true;
            app.RosanoiseButton = uitogglebutton(app.NoisemodeButtonGroup);
            app.RosanoiseButton.Text = 'Rosa noise';
            app.WhitenoiseButton = uitogglebutton(app.NoisemodeButtonGroup);
            app.WhitenoiseButton.Text = 'White noise';
            app.PlayButton = uibutton(app.GeneralvolumecomparisonTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.GeneralvolumecomparisonTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.GeneralvolumecomparisonTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GenerateButton = uibutton(app.GeneralvolumecomparisonTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SampleFrequency1sEditField_2Label = uilabel(app.GeneralvolumecomparisonTab);
            app.SampleFrequency1sEditField_2Label.Text = 'Sample Frequency (1/s)';
            app.SampleFrequencyVolumeEdit = uieditfield(app.GeneralvolumecomparisonTab, 'text');
            app.SampleFrequencyVolumeEdit.Value = '44100';
            app.SinglebandcomparisonTab = uitab(app.TabGroup);
            app.SinglebandcomparisonTab.Title = 'Single band comparison';
            app.DiagramTF_Noise = uiaxes(app.SinglebandcomparisonTab);
            title(app.DiagramTF_Noise, 'Transfer Function')
            app.DiagramUnfilteredNoise = uiaxes(app.SinglebandcomparisonTab);
            title(app.DiagramUnfilteredNoise, 'Unfiltered Data')
            app.DiagramFilteredNoise = uiaxes(app.SinglebandcomparisonTab);
            title(app.DiagramFilteredNoise, 'Filtered Data')
            app.DiagramUnfilteredFFTNoise = uiaxes(app.SinglebandcomparisonTab);
            title(app.DiagramUnfilteredFFTNoise, 'Unfiltered FFT')
            app.DiagramFilteredFFTNoise = uiaxes(app.SinglebandcomparisonTab);
            title(app.DiagramFilteredFFTNoise, 'Filtered FFT')
            app.ButtonGroup = uibuttongroup(app.SinglebandcomparisonTab);
            app.ButtonGroup.Title = 'Button Group';
            app.Band1Button = uiradiobutton(app.ButtonGroup);
            app.Band1Button.Text = 'Band 1';
            app.Band1Button.Value = true;
            app.Band2Button = uiradiobutton(app.ButtonGroup);
            app.Band2Button.Text = 'Band 2';
            app.Band3Button = uiradiobutton(app.ButtonGroup);
            app.Band3Button.Text = 'Band 3';
            app.Band4Button = uiradiobutton(app.ButtonGroup);
            app.Band4Button.Text = 'Band 4';
            app.Band5Button = uiradiobutton(app.ButtonGroup);
            app.Band5Button.Text = 'Band 5';
            app.Band6Button = uiradiobutton(app.ButtonGroup);
            app.Band6Button.Text = 'Band 6';
            app.Band7Button = uiradiobutton(app.ButtonGroup);
            app.Band7Button.Text = 'Band 7';
            app.Band8Button = uiradiobutton(app.ButtonGroup);
            app.Band8Button.Text = 'Band 8';
            app.Band9Button = uiradiobutton(app.ButtonGroup);
            app.Band9Button.Text = 'Band 9';
            app.Band10Button = uiradiobutton(app.ButtonGroup);
            app.Band10Button.Text = 'Band 10';
            app.BtnStopNoise = uibutton(app.SinglebandcomparisonTab, 'push');
            app.BtnStopNoise.ButtonPushedFcn = createCallbackFcn(app, @BtnStopNoiseButtonPushed, true);
            app.BtnStopNoise.Text = 'Stop Noise';
            app.BtnGenNoise = uibutton(app.SinglebandcomparisonTab, 'push');
            app.BtnGenNoise.ButtonPushedFcn = createCallbackFcn(app, @BtnGenNoisePushed, true);
            app.BtnGenNoise.Text = 'Generate Noise';
            app.Slider = uislider(app.SinglebandcomparisonTab);
            app.Slider.Limits = [-20 20];
            app.Slider.MajorTicks = [-20 -18 -16 -14 -12 -10 -8 -6 -4 -2 0 2 4 6 8 10 12 14 16 18 20];
            app.Slider.MinorTicks = [-19 -17 -15 -13 -11 -9 -7 -5 -3 -1 1 3 5 7 9 11 13 15 17 19];
            app.BtnPlayNoise = uibutton(app.SinglebandcomparisonTab, 'push');
            app.BtnPlayNoise.ButtonPushedFcn = createCallbackFcn(app, @BtnPlayNoiseButtonPushed, true);
            app.BtnPlayNoise.Text = 'Play';
            app.BtnSaveBandGain = uibutton(app.SinglebandcomparisonTab, 'push');
            app.BtnSaveBandGain.ButtonPushedFcn = createCallbackFcn(app, @BtnSaveBandGainButtonPushed, true);
            app.BtnSaveBandGain.Text = 'Save band gain';
            app.SignaldurationsEditFieldLabel = uilabel(app.SinglebandcomparisonTab);
            app.SignaldurationsEditFieldLabel.Text = 'Signal duration (s)';
            app.SignaldurationsEditField = uieditfield(app.SinglebandcomparisonTab, 'text');
            app.SignaldurationsEditField.Value = '60';
            app.SampleFrequency1sEditFieldLabel = uilabel(app.SinglebandcomparisonTab);
            app.SampleFrequency1sEditFieldLabel.Text = 'Sample Frequency (1/s)';
            app.SampleFrequency1sEditField = uieditfield(app.SinglebandcomparisonTab, 'text');
            app.SampleFrequency1sEditField.Value = '44100';
            app.EqualizerTab = uitab(app.TabGroup);
            app.EqualizerTab.Title = 'Equalizer';
            app.DiagramUnfiltered = uiaxes(app.EqualizerTab);
            title(app.DiagramUnfiltered, 'Unfiltered Data')
            app.DiagramFiltered = uiaxes(app.EqualizerTab);
            title(app.DiagramFiltered, 'Filtered Data')
            app.DiagramUnfilteredFFT = uiaxes(app.EqualizerTab);
            title(app.DiagramUnfilteredFFT, 'Unfiltered FFT')
            app.DiagramFilteredFFT = uiaxes(app.EqualizerTab);
            title(app.DiagramFilteredFFT, 'Filtered FFT')
            app.DiagramTF = uiaxes(app.EqualizerTab);
            title(app.DiagramTF, 'Transfer Function')
            app.BtnSave = uibutton(app.EqualizerTab, 'push');
            app.BtnSave.ButtonPushedFcn = createCallbackFcn(app, @BtnSaveButtonPushed, true);
            app.BtnSave.Text = {'Save'; 'coefficients'};
            app.BtnResetBw = uibutton(app.EqualizerTab, 'push');
            app.BtnResetBw.ButtonPushedFcn = createCallbackFcn(app, @BtnResetBwButtonPushed, true);
            app.BtnResetBw.Text = 'Reset Bw';
            app.BtnStop = uibutton(app.EqualizerTab, 'push');
            app.BtnStop.ButtonPushedFcn = createCallbackFcn(app, @BtnStopButtonPushed, true);
            app.BtnStop.Text = 'Stop';
            app.BtnPlay = uibutton(app.EqualizerTab, 'push');
            app.BtnPlay.ButtonPushedFcn = createCallbackFcn(app, @BtnPlayButtonPushed, true);
            app.BtnPlay.Text = 'Play';
            app.ResetGainsButton = uibutton(app.EqualizerTab, 'push');
            app.ResetGainsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGainsButtonPushed, true);
            app.ResetGainsButton.Text = 'Reset Gains';
            app.ResetOrderButton = uibutton(app.EqualizerTab, 'push');
            app.ResetOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOrderButtonPushed, true);
            app.ResetOrderButton.Text = 'Reset Order';
            app.ResetFButton = uibutton(app.EqualizerTab, 'push');
            app.ResetFButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFButtonPushed, true);
            app.ResetFButton.Text = 'Reset F';
            app.Panelf10 = uipanel(app.EqualizerTab);
            app.Panelf10.Title = '(11,3~22,6)kHz';
            app.Sliderf10 = uislider(app.Panelf10);
            app.Sliderf10.Limits = [-20 20];
            app.Sliderf10.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf10.ValueChangedFcn = createCallbackFcn(app, @Sliderf10_onChange, true);
            app.Sliderf10.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel = uilabel(app.Panelf10);
            app.dBEditFieldLabel.Text = 'dB';
            app.Editf10 = uieditfield(app.Panelf10, 'text');
            app.Editf10.ValueChangedFcn = createCallbackFcn(app, @Editf10_onChange, true);
            app.Editf10.Value = '0';
            app.OrderSpinnerLabel_10 = uilabel(app.Panelf10);
            app.OrderSpinnerLabel_10.Text = 'Order';
            app.SpnN10 = uispinner(app.Panelf10);
            app.SpnN10.Step = 2;
            app.SpnN10.Limits = [2 Inf];
            app.SpnN10.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN10.Value = 4;
            app.BandwidthSpinnerLabel_10 = uilabel(app.Panelf10);
            app.BandwidthSpinnerLabel_10.Text = 'Bandwidth';
            app.SpnBw10 = uispinner(app.Panelf10);
            app.SpnBw10.Step = 2000;
            app.SpnBw10.Limits = [1 Inf];
            app.SpnBw10.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw10.Value = 1;
            app.CenterFrequencySpinnerLabel_10 = uilabel(app.Panelf10);
            app.CenterFrequencySpinnerLabel_10.Text = 'Center Frequency';
            app.SpnF10 = uispinner(app.Panelf10);
            app.SpnF10.Step = 2000;
            app.SpnF10.Limits = [20 22000];
            app.SpnF10.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF10.Value = 20;
            app.Panelf9 = uipanel(app.EqualizerTab);
            app.Panelf9.Title = '(5,7~11,3)kHz';
            app.Sliderf9 = uislider(app.Panelf9);
            app.Sliderf9.Limits = [-20 20];
            app.Sliderf9.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf9.ValueChangedFcn = createCallbackFcn(app, @Sliderf9_onChange, true);
            app.Sliderf9.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_2 = uilabel(app.Panelf9);
            app.dBEditFieldLabel_2.Text = 'dB';
            app.Editf9 = uieditfield(app.Panelf9, 'text');
            app.Editf9.ValueChangedFcn = createCallbackFcn(app, @Editf9_onChange, true);
            app.Editf9.Value = '0';
            app.OrderSpinnerLabel_9 = uilabel(app.Panelf9);
            app.OrderSpinnerLabel_9.Text = 'Order';
            app.SpnN9 = uispinner(app.Panelf9);
            app.SpnN9.Step = 2;
            app.SpnN9.Limits = [2 Inf];
            app.SpnN9.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN9.Value = 4;
            app.BandwidthSpinnerLabel_9 = uilabel(app.Panelf9);
            app.BandwidthSpinnerLabel_9.Text = 'Bandwidth';
            app.SpnBw9 = uispinner(app.Panelf9);
            app.SpnBw9.Step = 1000;
            app.SpnBw9.Limits = [1 Inf];
            app.SpnBw9.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw9.Value = 1;
            app.CenterFrequencySpinnerLabel_9 = uilabel(app.Panelf9);
            app.CenterFrequencySpinnerLabel_9.Text = 'Center Frequency';
            app.SpnF9 = uispinner(app.Panelf9);
            app.SpnF9.Step = 1000;
            app.SpnF9.Limits = [20 22000];
            app.SpnF9.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF9.Value = 20;
            app.Panelf8 = uipanel(app.EqualizerTab);
            app.Panelf8.Title = '(2,8~5,7)kHz';
            app.Sliderf8 = uislider(app.Panelf8);
            app.Sliderf8.Limits = [-20 20];
            app.Sliderf8.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf8.ValueChangedFcn = createCallbackFcn(app, @Sliderf8_onChange, true);
            app.Sliderf8.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_3 = uilabel(app.Panelf8);
            app.dBEditFieldLabel_3.Text = 'dB';
            app.Editf8 = uieditfield(app.Panelf8, 'text');
            app.Editf8.ValueChangedFcn = createCallbackFcn(app, @Editf8_onChange, true);
            app.Editf8.Value = '0';
            app.OrderSpinnerLabel_8 = uilabel(app.Panelf8);
            app.OrderSpinnerLabel_8.Text = 'Order';
            app.SpnN8 = uispinner(app.Panelf8);
            app.SpnN8.Step = 2;
            app.SpnN8.Limits = [2 Inf];
            app.SpnN8.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN8.Value = 4;
            app.BandwidthSpinnerLabel_8 = uilabel(app.Panelf8);
            app.BandwidthSpinnerLabel_8.Text = 'Bandwidth';
            app.SpnBw8 = uispinner(app.Panelf8);
            app.SpnBw8.Step = 500;
            app.SpnBw8.Limits = [1 Inf];
            app.SpnBw8.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw8.Value = 1;
            app.CenterFrequencySpinnerLabel_8 = uilabel(app.Panelf8);
            app.CenterFrequencySpinnerLabel_8.Text = 'Center Frequency';
            app.SpnF8 = uispinner(app.Panelf8);
            app.SpnF8.Step = 500;
            app.SpnF8.Limits = [20 22000];
            app.SpnF8.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF8.Value = 20;
            app.Panelf7 = uipanel(app.EqualizerTab);
            app.Panelf7.Title = '(1,4~2,8)kHz';
            app.Sliderf7 = uislider(app.Panelf7);
            app.Sliderf7.Limits = [-20 20];
            app.Sliderf7.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf7.ValueChangedFcn = createCallbackFcn(app, @Sliderf7_onChange, true);
            app.Sliderf7.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_4 = uilabel(app.Panelf7);
            app.dBEditFieldLabel_4.Text = 'dB';
            app.Editf7 = uieditfield(app.Panelf7, 'text');
            app.Editf7.ValueChangedFcn = createCallbackFcn(app, @Editf7_onChange, true);
            app.Editf7.Value = '0';
            app.OrderSpinnerLabel_7 = uilabel(app.Panelf7);
            app.OrderSpinnerLabel_7.Text = 'Order';
            app.SpnN7 = uispinner(app.Panelf7);
            app.SpnN7.Step = 2;
            app.SpnN7.Limits = [2 Inf];
            app.SpnN7.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN7.Value = 4;
            app.BandwidthSpinnerLabel_7 = uilabel(app.Panelf7);
            app.BandwidthSpinnerLabel_7.Text = 'Bandwidth';
            app.SpnBw7 = uispinner(app.Panelf7);
            app.SpnBw7.Step = 300;
            app.SpnBw7.Limits = [1 Inf];
            app.SpnBw7.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw7.Value = 1;
            app.CenterFrequencySpinnerLabel_7 = uilabel(app.Panelf7);
            app.CenterFrequencySpinnerLabel_7.Text = 'Center Frequency';
            app.SpnF7 = uispinner(app.Panelf7);
            app.SpnF7.Step = 200;
            app.SpnF7.Limits = [20 22000];
            app.SpnF7.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF7.Value = 20;
            app.Panelf6 = uipanel(app.EqualizerTab);
            app.Panelf6.Title = '(707~1414)Hz';
            app.Sliderf6 = uislider(app.Panelf6);
            app.Sliderf6.Limits = [-20 20];
            app.Sliderf6.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf6.ValueChangedFcn = createCallbackFcn(app, @Sliderf6_onChange, true);
            app.Sliderf6.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_5 = uilabel(app.Panelf6);
            app.dBEditFieldLabel_5.Text = 'dB';
            app.Editf6 = uieditfield(app.Panelf6, 'text');
            app.Editf6.ValueChangedFcn = createCallbackFcn(app, @Editf6_onChange, true);
            app.Editf6.Value = '0';
            app.OrderSpinnerLabel_6 = uilabel(app.Panelf6);
            app.OrderSpinnerLabel_6.Text = 'Order';
            app.SpnN6 = uispinner(app.Panelf6);
            app.SpnN6.Step = 2;
            app.SpnN6.Limits = [2 Inf];
            app.SpnN6.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN6.Value = 4;
            app.BandwidthSpinnerLabel_6 = uilabel(app.Panelf6);
            app.BandwidthSpinnerLabel_6.Text = 'Bandwidth';
            app.SpnBw6 = uispinner(app.Panelf6);
            app.SpnBw6.Step = 100;
            app.SpnBw6.Limits = [1 Inf];
            app.SpnBw6.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw6.Value = 1;
            app.CenterFrequencySpinnerLabel_6 = uilabel(app.Panelf6);
            app.CenterFrequencySpinnerLabel_6.Text = 'Center Frequency';
            app.SpnF6 = uispinner(app.Panelf6);
            app.SpnF6.Step = 100;
            app.SpnF6.Limits = [20 22000];
            app.SpnF6.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF6.Value = 20;
            app.Panelf5 = uipanel(app.EqualizerTab);
            app.Panelf5.Title = '(354~707)Hz';
            app.Sliderf5 = uislider(app.Panelf5);
            app.Sliderf5.Limits = [-20 20];
            app.Sliderf5.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf5.ValueChangedFcn = createCallbackFcn(app, @Sliderf5_onChange, true);
            app.Sliderf5.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_6 = uilabel(app.Panelf5);
            app.dBEditFieldLabel_6.Text = 'dB';
            app.Editf5 = uieditfield(app.Panelf5, 'text');
            app.Editf5.ValueChangedFcn = createCallbackFcn(app, @Editf5_onChange, true);
            app.Editf5.Value = '0';
            app.OrderSpinnerLabel_5 = uilabel(app.Panelf5);
            app.OrderSpinnerLabel_5.Text = 'Order';
            app.SpnN5 = uispinner(app.Panelf5);
            app.SpnN5.Step = 2;
            app.SpnN5.Limits = [2 Inf];
            app.SpnN5.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN5.Value = 4;
            app.BandwidthSpinnerLabel_5 = uilabel(app.Panelf5);
            app.BandwidthSpinnerLabel_5.Text = 'Bandwidth';
            app.SpnBw5 = uispinner(app.Panelf5);
            app.SpnBw5.Step = 75;
            app.SpnBw5.Limits = [1 Inf];
            app.SpnBw5.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw5.Value = 1;
            app.CenterFrequencySpinnerLabel_5 = uilabel(app.Panelf5);
            app.CenterFrequencySpinnerLabel_5.Text = 'Center Frequency';
            app.SpnF5 = uispinner(app.Panelf5);
            app.SpnF5.Step = 50;
            app.SpnF5.Limits = [20 22000];
            app.SpnF5.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF5.Value = 20;
            app.Panelf4 = uipanel(app.EqualizerTab);
            app.Panelf4.Title = '(177~354)Hz';
            app.Sliderf4 = uislider(app.Panelf4);
            app.Sliderf4.Limits = [-20 20];
            app.Sliderf4.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf4.ValueChangedFcn = createCallbackFcn(app, @Sliderf4_onChange, true);
            app.Sliderf4.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_7 = uilabel(app.Panelf4);
            app.dBEditFieldLabel_7.Text = 'dB';
            app.Editf4 = uieditfield(app.Panelf4, 'text');
            app.Editf4.ValueChangedFcn = createCallbackFcn(app, @Editf4_onChange, true);
            app.Editf4.Value = '0';
            app.OrderSpinnerLabel_4 = uilabel(app.Panelf4);
            app.OrderSpinnerLabel_4.Text = 'Order';
            app.SpnN4 = uispinner(app.Panelf4);
            app.SpnN4.Step = 2;
            app.SpnN4.Limits = [2 Inf];
            app.SpnN4.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN4.Value = 4;
            app.BandwidthSpinnerLabel_4 = uilabel(app.Panelf4);
            app.BandwidthSpinnerLabel_4.Text = 'Bandwidth';
            app.SpnBw4 = uispinner(app.Panelf4);
            app.SpnBw4.Step = 50;
            app.SpnBw4.Limits = [1 Inf];
            app.SpnBw4.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw4.Value = 1;
            app.CenterFrequencySpinnerLabel_4 = uilabel(app.Panelf4);
            app.CenterFrequencySpinnerLabel_4.Text = 'Center Frequency';
            app.SpnF4 = uispinner(app.Panelf4);
            app.SpnF4.Step = 20;
            app.SpnF4.Limits = [20 22000];
            app.SpnF4.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF4.Value = 20;
            app.Panelf3 = uipanel(app.EqualizerTab);
            app.Panelf3.Title = '(88~177)Hz';
            app.Sliderf3 = uislider(app.Panelf3);
            app.Sliderf3.Limits = [-20 20];
            app.Sliderf3.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf3.ValueChangedFcn = createCallbackFcn(app, @Sliderf3_onChange, true);
            app.Sliderf3.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_8 = uilabel(app.Panelf3);
            app.dBEditFieldLabel_8.Text = 'dB';
            app.Editf3 = uieditfield(app.Panelf3, 'text');
            app.Editf3.ValueChangedFcn = createCallbackFcn(app, @Editf3_onChange, true);
            app.Editf3.Value = '0';
            app.OrderSpinnerLabel_3 = uilabel(app.Panelf3);
            app.OrderSpinnerLabel_3.Text = 'Order';
            app.SpnN3 = uispinner(app.Panelf3);
            app.SpnN3.Step = 2;
            app.SpnN3.Limits = [2 Inf];
            app.SpnN3.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN3.Value = 4;
            app.BandwidthSpinnerLabel_3 = uilabel(app.Panelf3);
            app.BandwidthSpinnerLabel_3.Text = 'Bandwidth';
            app.SpnBw3 = uispinner(app.Panelf3);
            app.SpnBw3.Step = 20;
            app.SpnBw3.Limits = [1 Inf];
            app.SpnBw3.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw3.Value = 1;
            app.CenterFrequencySpinnerLabel_3 = uilabel(app.Panelf3);
            app.CenterFrequencySpinnerLabel_3.Text = 'Center Frequency';
            app.SpnF3 = uispinner(app.Panelf3);
            app.SpnF3.Step = 10;
            app.SpnF3.Limits = [20 22000];
            app.SpnF3.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF3.Value = 20;
            app.Panelf2 = uipanel(app.EqualizerTab);
            app.Panelf2.Title = '(44~88)Hz';
            app.Sliderf2 = uislider(app.Panelf2);
            app.Sliderf2.Limits = [-20 20];
            app.Sliderf2.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf2.ValueChangedFcn = createCallbackFcn(app, @Sliderf2_onChange, true);
            app.Sliderf2.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_9 = uilabel(app.Panelf2);
            app.dBEditFieldLabel_9.Text = 'dB';
            app.Editf2 = uieditfield(app.Panelf2, 'text');
            app.Editf2.ValueChangedFcn = createCallbackFcn(app, @Editf2_onChange, true);
            app.Editf2.Value = '0';
            app.OrderSpinnerLabel_11 = uilabel(app.Panelf2);
            app.OrderSpinnerLabel_11.Text = 'Order';
            app.SpnN2 = uispinner(app.Panelf2);
            app.SpnN2.Step = 2;
            app.SpnN2.Limits = [2 Inf];
            app.SpnN2.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN2.Value = 4;
            app.BandwidthSpinnerLabel_11 = uilabel(app.Panelf2);
            app.BandwidthSpinnerLabel_11.Text = 'Bandwidth';
            app.SpnBw2 = uispinner(app.Panelf2);
            app.SpnBw2.Step = 5;
            app.SpnBw2.Limits = [1 Inf];
            app.SpnBw2.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw2.Value = 1;
            app.CenterFrequencySpinnerLabel_11 = uilabel(app.Panelf2);
            app.CenterFrequencySpinnerLabel_11.Text = 'Center Frequency';
            app.SpnF2 = uispinner(app.Panelf2);
            app.SpnF2.Step = 5;
            app.SpnF2.Limits = [20 22000];
            app.SpnF2.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF2.Value = 20;
            app.Panelf1 = uipanel(app.EqualizerTab);
            app.Panelf1.Title = '(22~44)Hz';
            app.Sliderf1 = uislider(app.Panelf1);
            app.Sliderf1.Limits = [-20 20];
            app.Sliderf1.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            app.Sliderf1.ValueChangedFcn = createCallbackFcn(app, @Sliderf1_onChange, true);
            app.Sliderf1.MinorTicks = [-20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.dBEditFieldLabel_10 = uilabel(app.Panelf1);
            app.dBEditFieldLabel_10.Text = 'dB';
            app.Editf1 = uieditfield(app.Panelf1, 'text');
            app.Editf1.ValueChangedFcn = createCallbackFcn(app, @Editf1_onChange, true);
            app.Editf1.Value = '0';
            app.CenterFrequencySpinnerLabel = uilabel(app.Panelf1);
            app.CenterFrequencySpinnerLabel.Text = 'Center Frequency';
            app.SpnF1 = uispinner(app.Panelf1);
            app.SpnF1.Step = 5;
            app.SpnF1.Limits = [20 22000];
            app.SpnF1.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnF1.Value = 20;
            app.BandwidthSpinnerLabel = uilabel(app.Panelf1);
            app.BandwidthSpinnerLabel.Text = 'Bandwidth';
            app.SpnBw1 = uispinner(app.Panelf1);
            app.SpnBw1.Step = 5;
            app.SpnBw1.Limits = [1 Inf];
            app.SpnBw1.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnBw1.Value = 1;
            app.OrderSpinnerLabel = uilabel(app.Panelf1);
            app.OrderSpinnerLabel.Text = 'Order';
            app.SpnN1 = uispinner(app.Panelf1);
            app.SpnN1.Step = 2;
            app.SpnN1.Limits = [2 Inf];
            app.SpnN1.ValueChangedFcn = createCallbackFcn(app, @CallbackUpdatepEQ, true);
            app.SpnN1.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_pEQ_live

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end