classdef binaural < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    CombineToBRIR                 matlab.ui.control.CheckBox
    BrowseButtonHP                matlab.ui.control.Button
    BrowseButtonRoom              matlab.ui.control.Button
    CalibrateButton               matlab.ui.control.Button
    TargetSampleRatekHzDropDown   matlab.ui.control.DropDown
    TargetSampleRatekHzDropDownLabel  matlab.ui.control.Label
    MultichannelaudioButtonGroup  matlab.ui.container.ButtonGroup
    RRCheckBox                    matlab.ui.control.CheckBox
    RLCheckBox                    matlab.ui.control.CheckBox
    LFECheckBox                   matlab.ui.control.CheckBox
    SRCheckBox                    matlab.ui.control.CheckBox
    SLCheckBox                    matlab.ui.control.CheckBox
    FRCheckBox                    matlab.ui.control.CheckBox
    CCheckBox                     matlab.ui.control.CheckBox
    FLCheckBox                    matlab.ui.control.CheckBox
    Dolby71Button                 matlab.ui.control.RadioButton
    Dolby51Button                 matlab.ui.control.RadioButton
    StereoButton                  matlab.ui.control.RadioButton
    MeasurementsetupPanel         matlab.ui.container.Panel
    HeadphoneiterationsEditField  matlab.ui.control.NumericEditField
    HeadphoneiterationsEditFieldLabel  matlab.ui.control.Label
    HeadpositionsDropDown         matlab.ui.control.DropDown
    HeadpositionsDropDownLabel    matlab.ui.control.Label
    RangeEditField                matlab.ui.control.NumericEditField
    RangeEditFieldLabel           matlab.ui.control.Label
    TrainingsignalPanel           matlab.ui.container.Panel
    SampleRateEditField           matlab.ui.control.NumericEditField
    SampleRatekHzLabel            matlab.ui.control.Label
    PauseEditField                matlab.ui.control.NumericEditField
    PausesLabel                   matlab.ui.control.Label
    DurationEditField             matlab.ui.control.NumericEditField
    DurationsignalsLabel          matlab.ui.control.Label
    FrequencyMaxEditField         matlab.ui.control.NumericEditField
    toEditFieldLabel              matlab.ui.control.Label
    FrequencyMinEditField         matlab.ui.control.NumericEditField
    FrequencyHzLabel              matlab.ui.control.Label
    AppendHPDateCheckBox          matlab.ui.control.CheckBox
    CalculateCheckBoxHeadphone    matlab.ui.control.CheckBox
    CalculateCheckBoxRoom         matlab.ui.control.CheckBox
    AppendRoomDateCheckBox        matlab.ui.control.CheckBox
    StartButton                   matlab.ui.control.Button
    HeadphoneNameEditField        matlab.ui.control.EditField
    HeadphoneNameEditFieldLabel   matlab.ui.control.Label
    RoomNameEditField             matlab.ui.control.EditField
    RoomNameEditFieldLabel        matlab.ui.control.Label
    BinauralSynthesizerLabel      matlab.ui.control.Label
    MeasureCheckBoxHP             matlab.ui.control.CheckBox
    MeasureCheckBoxRoom           matlab.ui.control.CheckBox
  end


    properties (Access = private)
        fSampleRateTarget %    = 44.1e3;
        sRoomName
        sHeadphoneName
    end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
            clc; close all; disp('Binaural Realizer'); disp('=================');
            addpath('utils');
            if exist('utils/ActiveFilter.mat','file')
              load('utils/ActiveFilter','sHeadphoneName','sRoomName','sSetup');
              switch sSetup
                case '2.0'
                  app.MultichannelaudioButtonGroup.SelectedObject=app.StereoButton;
                  app.FLCheckBox.Value    = true;
                  app.FRCheckBox.Value    = true;
                  app.CCheckBox.Value     = false;
                  app.LFECheckBox.Value   = false;
                  app.SLCheckBox.Value    = false;
                  app.SRCheckBox.Value    = false;
                case '5.1'
                  app.MultichannelaudioButtonGroup.SelectedObject=app.Dolby51Button;
                  app.FLCheckBox.Value    = true;
                  app.FRCheckBox.Value    = true;
                  app.CCheckBox.Value     = true;
                  app.LFECheckBox.Value   = true;
                  app.SLCheckBox.Value    = true;
                  app.SRCheckBox.Value    = true;                  
              end
            else
                sRoomName = 'Test_Room';
                sHeadphoneName = 'Test_Headphone';
                set(app.AppendRoomDateCheckBox,'Value',true);
                set(app.AppendHPDateCheckBox,'Value',true);
            end
            set(app.RoomNameEditField,'Value',sRoomName);
            set(app.HeadphoneNameEditField,'Value',sHeadphoneName);
    end

    % Button pushed function: StartButton
    function StartButtonPushed(app, event)
            % append date to room name?
            if app.AppendRoomDateCheckBox.Value
              app.sRoomName = [app.RoomNameEditField.Value,'_',datestr(now,'yymmdd')];
            else
              app.sRoomName = app.RoomNameEditField.Value;
            end
            % append date to headphone name?
            if app.AppendHPDateCheckBox.Value
              app.sHeadphoneName = [app.HeadphoneNameEditField.Value,'_',datestr(now,'yymmdd')];
            else
              app.sHeadphoneName = app.HeadphoneNameEditField.Value;
            end
            % Stereo, Dolby 5.1, active channels
            switch app.MultichannelaudioButtonGroup.SelectedObject
              case app.StereoButton
                stMeas.iNoTx        = 2;
                stMeas.vActTx       = zeros(1,stMeas.iNoTx);
                stMeas.vActTx(1)    = app.FLCheckBox.Value;
                stMeas.vActTx(2)    = app.FRCheckBox.Value;
              case app.Dolby51Button
                stMeas.iNoTx        = 6;
                stMeas.vActTx       = zeros(1,stMeas.iNoTx);
                stMeas.vActTx(1)    = app.FLCheckBox.Value;
                stMeas.vActTx(2)    = app.FRCheckBox.Value;
                stMeas.vActTx(3)    = app.CCheckBox.Value;
                stMeas.vActTx(4)    = app.LFECheckBox.Value;
                stMeas.vActTx(5)    = app.SLCheckBox.Value;
                stMeas.vActTx(6)    = app.SRCheckBox.Value;
            end
            if app.CalculateCheckBoxRoom.Value && app.CalculateCheckBoxHeadphone.Value
              set(app.CombineToBRIR,'Enable',true);
            else
              set(app.CombineToBRIR,'Enable',false);
            end
            stMeas.fSampleRate  = app.SampleRateEditField.Value;
            stMeas.fFreqMin     = app.FrequencyMinEditField.Value;
            stMeas.fFreqMax     = app.FrequencyMaxEditField.Value;
            stMeas.fDur         = app.DurationEditField.Value;
            stMeas.fPause       = app.PauseEditField.Value;
            stMeas.iNoHPMeas    = app.HeadphoneiterationsEditField.Value;
            stMeas.fHeadPosRange = app.RangeEditField.Value;
            stMeas.iNoHeadPos   = str2double(app.HeadpositionsDropDown.Value);
            app.fSampleRateTarget = str2double(app.TargetSampleRatekHzDropDown.Value)*1e3;
            %stMeas.bHeadtracker = app.HeadtrackerCheckBox.Value;
            % measure PRIR?
            if app.MeasureCheckBoxRoom.Value
              switch app.MultichannelaudioButtonGroup.SelectedObject
                case app.StereoButton
                  MeasPRIR('2.0',app.sRoomName,stMeas);
                case app.Dolby51Button
                  MeasPRIR('5.1',app.sRoomName,stMeas);
              end

            end
            % measure HPIR?
            if app.MeasureCheckBoxHP.Value
              MeasHPIR(app.sHeadphoneName,stMeas);
            end
            % calculate PRIR?
            if app.CalculateCheckBoxRoom.Value
              switch app.MultichannelaudioButtonGroup.SelectedObject
                case app.StereoButton
                  CalcPRIR('2.0',app.sRoomName);
                case app.Dolby51Button
                  CalcPRIR('5.1',app.sRoomName);
              end
            end
            % calculate HPIR?
            if app.CalculateCheckBoxHeadphone.Value
              CalcHPIR(app.sHeadphoneName);
            end
            %% new
            if app.CalculateCheckBoxRoom.Value
              if app.CalculateCheckBoxHeadphone.Value
                sRoomName = app.sRoomName;
                sHeadphoneName = app.sHeadphoneName;
              else
                sRoomName = app.sRoomName;
                sHeadphoneName = 'none';
              end
              switch app.MultichannelaudioButtonGroup.SelectedObject
                case app.StereoButton
                  CalcBRIR('2.0',sRoomName,sHeadphoneName,app.fSampleRateTarget);
                case app.Dolby51Button
                  CalcBRIR('5.1',sRoomName,sHeadphoneName,app.fSampleRateTarget);
              end
              % Activate Filter
              sBRIRName         = ['Room_',sRoomName,'_HP_',sHeadphoneName];
              switch stMeas.iNoTx
                case 2
                  sSetup = '2.0';
                case 6
                  sSetup = '5.1';
              end
              save('utils/ActiveFilter','sRoomName','sHeadphoneName','sBRIRName','sSetup');
            end

            %%
            % Combine PRIR && HPIR
            if app.CombineToBRIR.Value
              switch app.MultichannelaudioButtonGroup.SelectedObject
                case app.StereoButton
                  CalcBRIR('2.0',app.sRoomName,app.sHeadphoneName,app.fSampleRateTarget);
                case app.Dolby51Button
                  CalcBRIR('5.1',app.sRoomName,app.sHeadphoneName,app.fSampleRateTarget);
              end
              % Activate Filter
              sRoomName         = app.sRoomName;
              sHeadphoneName    = app.sHeadphoneName;
              sBRIRName         = ['Room_',sRoomName,'_HP_',sHeadphoneName];
              switch stMeas.iNoTx
                case 2
                  sSetup = '2.0';
                case 6
                  sSetup = '5.1';
              end
              save('utils/ActiveFilter','sRoomName','sHeadphoneName','sBRIRName','sSetup');
            end
    end

    % Selection changed function: MultichannelaudioButtonGroup
    function MultichannelaudioButtonGroupSelectionChanged(app, event)
            switch app.MultichannelaudioButtonGroup.SelectedObject
                case app.StereoButton
                    app.FLCheckBox.Value    = true;
                    app.FRCheckBox.Value    = true;
                    app.CCheckBox.Value     = false;
                    app.LFECheckBox.Value   = false; 
                    app.SLCheckBox.Value    = false;
                    app.SRCheckBox.Value    = false;                    
                case app.Dolby51Button
                    app.FLCheckBox.Value    = true;
                    app.FRCheckBox.Value    = true;                    
                    app.CCheckBox.Value     = true;
                    app.LFECheckBox.Value   = true;                    
                    app.SLCheckBox.Value    = true;
                    app.SRCheckBox.Value    = true;                    
            end            
    end

    % Button pushed function: CalibrateButton
    function CalibrateButtonPushed(app, event)
            Calibrate();
    end

    % Button pushed function: BrowseButtonRoom
    function BrowseButtonRoomPushed(app, event)
            if ispc
              li = '\';
            else
              li = '/';
            end
            sSelPath = uigetdir(['PRIR',li],'Select room folder'); figure(app.UIFigure);
            if numel(strfind(sSelPath,['2.0',li]))>0
               sPath    = extractAfter(sSelPath,[li,'PRIR',li,'2.0',li]);
               app.MultichannelaudioButtonGroup.SelectedObject=app.StereoButton;
               app.FLCheckBox.Value    = true;
               app.FRCheckBox.Value    = true;
               app.CCheckBox.Value     = false;
               app.LFECheckBox.Value   = false; 
               app.SLCheckBox.Value    = false;
               app.SRCheckBox.Value    = false;                    
            elseif numel(strfind(sSelPath,['5.1',li]))>0
               sPath    = extractAfter(sSelPath,[li,'PRIR',li,'5.1',li]);
               app.MultichannelaudioButtonGroup.SelectedObject=app.Dolby51Button;
               app.FLCheckBox.Value    = true;
               app.FRCheckBox.Value    = true;                    
               app.CCheckBox.Value     = true;
               app.LFECheckBox.Value   = true;                    
               app.SLCheckBox.Value    = true;
               app.SRCheckBox.Value    = true;                                   
            end
            set(app.RoomNameEditField,'Value',sPath);
            set(app.AppendRoomDateCheckBox,'Value',false);
    end

    % Button pushed function: BrowseButtonHP
    function BrowseButtonHPPushed(app, event)
            if ispc
              li = '\';
            else
              li = '/';
            end      
            sSelPath = uigetdir(['HPIR',li],'Select headphone folder'); figure(app.UIFigure);
            sPath    = extractAfter(sSelPath,[li,'HPIR',li]);
            set(app.HeadphoneNameEditField,'Value',sPath);
            set(app.AppendHPDateCheckBox,'Value',false);            
    end

    % Value changed function: CalculateCheckBoxRoom
    function CalculateCheckBoxRoomValueChanged(app, event)
      value = app.CalculateCheckBoxRoom.Value;
      if value && app.CalculateCheckBoxHeadphone.Value
        set(app.CombineToBRIR,'Enable',true);
      else
        set(app.CombineToBRIR,'Enable',false);
      end
    end

    % Value changed function: CalculateCheckBoxHeadphone
    function CalculateCheckBoxHeadphoneValueChanged(app, event)
      value = app.CalculateCheckBoxHeadphone.Value;
      if value && app.CalculateCheckBoxRoom.Value
        set(app.CombineToBRIR,'Enable',true);
      else
        set(app.CombineToBRIR,'Enable',false);
      end      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'UI Figure';
      app.MeasureCheckBoxRoom = uicheckbox(app.UIFigure);
      app.MeasureCheckBoxRoom.Text = 'Measure';
      app.MeasureCheckBoxHP = uicheckbox(app.UIFigure);
      app.MeasureCheckBoxHP.Text = 'Measure';
      app.BinauralSynthesizerLabel = uilabel(app.UIFigure);
      app.BinauralSynthesizerLabel.Text = 'Binaural Synthesizer';
      app.RoomNameEditFieldLabel = uilabel(app.UIFigure);
      app.RoomNameEditFieldLabel.Text = 'Room Name';
      app.RoomNameEditField = uieditfield(app.UIFigure, 'text');
      app.RoomNameEditField.Value = 'Test_Room';
      app.HeadphoneNameEditFieldLabel = uilabel(app.UIFigure);
      app.HeadphoneNameEditFieldLabel.Text = 'Headphone Name';
      app.HeadphoneNameEditField = uieditfield(app.UIFigure, 'text');
      app.HeadphoneNameEditField.Value = 'Test_Headphone';
      app.StartButton = uibutton(app.UIFigure, 'push');
      app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
      app.StartButton.Text = 'Start';
      app.AppendRoomDateCheckBox = uicheckbox(app.UIFigure);
      app.AppendRoomDateCheckBox.Text = 'Append Date';
      app.CalculateCheckBoxRoom = uicheckbox(app.UIFigure);
      app.CalculateCheckBoxRoom.ValueChangedFcn = createCallbackFcn(app, @CalculateCheckBoxRoomValueChanged, true);
      app.CalculateCheckBoxRoom.Text = 'Calculate';
      app.CalculateCheckBoxRoom.Value = true;
      app.CalculateCheckBoxHeadphone = uicheckbox(app.UIFigure);
      app.CalculateCheckBoxHeadphone.ValueChangedFcn = createCallbackFcn(app, @CalculateCheckBoxHeadphoneValueChanged, true);
      app.CalculateCheckBoxHeadphone.Text = 'Calculate';
      app.CalculateCheckBoxHeadphone.Value = true;
      app.AppendHPDateCheckBox = uicheckbox(app.UIFigure);
      app.AppendHPDateCheckBox.Text = 'Append Date';
      app.TrainingsignalPanel = uipanel(app.UIFigure);
      app.TrainingsignalPanel.Title = 'Training signal';
      app.FrequencyHzLabel = uilabel(app.TrainingsignalPanel);
      app.FrequencyHzLabel.Text = 'Frequency [Hz]';
      app.FrequencyMinEditField = uieditfield(app.TrainingsignalPanel, 'numeric');
      app.FrequencyMinEditField.Limits = [5 50];
      app.FrequencyMinEditField.ValueDisplayFormat = '%.0f';
      app.FrequencyMinEditField.Value = 10;
      app.toEditFieldLabel = uilabel(app.TrainingsignalPanel);
      app.toEditFieldLabel.Text = 'to';
      app.FrequencyMaxEditField = uieditfield(app.TrainingsignalPanel, 'numeric');
      app.FrequencyMaxEditField.Limits = [10000 24000];
      app.FrequencyMaxEditField.ValueDisplayFormat = '%.0f';
      app.FrequencyMaxEditField.Value = 24000;
      app.DurationsignalsLabel = uilabel(app.TrainingsignalPanel);
      app.DurationsignalsLabel.Text = 'Duration signal [s]';
      app.DurationEditField = uieditfield(app.TrainingsignalPanel, 'numeric');
      app.DurationEditField.Limits = [1 20];
      app.DurationEditField.ValueDisplayFormat = '%.1f';
      app.DurationEditField.Value = 3;
      app.PausesLabel = uilabel(app.TrainingsignalPanel);
      app.PausesLabel.Text = 'Pause [s]';
      app.PauseEditField = uieditfield(app.TrainingsignalPanel, 'numeric');
      app.PauseEditField.Limits = [0 3];
      app.PauseEditField.ValueDisplayFormat = '%.1f';
      app.PauseEditField.Value = 1;
      app.SampleRatekHzLabel = uilabel(app.TrainingsignalPanel);
      app.SampleRatekHzLabel.Enable = 'off';
      app.SampleRatekHzLabel.Text = 'Sample Rate [kHz]';
      app.SampleRateEditField = uieditfield(app.TrainingsignalPanel, 'numeric');
      app.SampleRateEditField.Limits = [44.1 96];
      app.SampleRateEditField.ValueDisplayFormat = '%.0f';
      app.SampleRateEditField.Editable = 'off';
      app.SampleRateEditField.Enable = 'off';
      app.SampleRateEditField.Value = 48;
      app.MeasurementsetupPanel = uipanel(app.UIFigure);
      app.MeasurementsetupPanel.Title = 'Measurement setup';
      app.RangeEditFieldLabel = uilabel(app.MeasurementsetupPanel);
      app.RangeEditFieldLabel.Text = 'Range [°]';
      app.RangeEditField = uieditfield(app.MeasurementsetupPanel, 'numeric');
      app.RangeEditField.Limits = [0 180];
      app.RangeEditField.Value = 60;
      app.HeadpositionsDropDownLabel = uilabel(app.MeasurementsetupPanel);
      app.HeadpositionsDropDownLabel.Text = 'Head positions';
      app.HeadpositionsDropDown = uidropdown(app.MeasurementsetupPanel);
      app.HeadpositionsDropDown.Items = {'1', '3', '5', '7', '9', '13', '25'};
      app.HeadpositionsDropDown.Value = '9';
      app.HeadphoneiterationsEditFieldLabel = uilabel(app.MeasurementsetupPanel);
      app.HeadphoneiterationsEditFieldLabel.Text = 'Headphone iterations';
      app.HeadphoneiterationsEditField = uieditfield(app.MeasurementsetupPanel, 'numeric');
      app.HeadphoneiterationsEditField.Limits = [1 5];
      app.HeadphoneiterationsEditField.ValueDisplayFormat = '%.0f';
      app.HeadphoneiterationsEditField.Value = 1;
      app.MultichannelaudioButtonGroup = uibuttongroup(app.UIFigure);
      app.MultichannelaudioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MultichannelaudioButtonGroupSelectionChanged, true);
      app.MultichannelaudioButtonGroup.Title = 'Multichannel audio';
      app.StereoButton = uiradiobutton(app.MultichannelaudioButtonGroup);
      app.StereoButton.Text = 'Stereo';
      app.StereoButton.Value = true;
      app.Dolby51Button = uiradiobutton(app.MultichannelaudioButtonGroup);
      app.Dolby51Button.Text = 'Dolby 5.1';
      app.Dolby71Button = uiradiobutton(app.MultichannelaudioButtonGroup);
      app.Dolby71Button.Enable = 'off';
      app.Dolby71Button.Text = 'Dolby 7.1';
      app.FLCheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.FLCheckBox.Text = 'FL';
      app.FLCheckBox.Value = true;
      app.CCheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.CCheckBox.Text = 'C';
      app.FRCheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.FRCheckBox.Text = 'FR';
      app.FRCheckBox.Value = true;
      app.SLCheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.SLCheckBox.Text = 'SL';
      app.SRCheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.SRCheckBox.Text = 'SR';
      app.LFECheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.LFECheckBox.Text = 'LFE';
      app.RLCheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.RLCheckBox.Enable = 'off';
      app.RLCheckBox.Text = 'RL';
      app.RRCheckBox = uicheckbox(app.MultichannelaudioButtonGroup);
      app.RRCheckBox.Enable = 'off';
      app.RRCheckBox.Text = 'RR';
      app.TargetSampleRatekHzDropDownLabel = uilabel(app.UIFigure);
      app.TargetSampleRatekHzDropDownLabel.Text = 'Target Sample Rate [kHz]';
      app.TargetSampleRatekHzDropDown = uidropdown(app.UIFigure);
      app.TargetSampleRatekHzDropDown.Items = {'44.1', '48', '88.2', '96', '176.4', '192'};
      app.TargetSampleRatekHzDropDown.Value = '44.1';
      app.CalibrateButton = uibutton(app.UIFigure, 'push');
      app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
      app.CalibrateButton.Text = 'Calibrate';
      app.BrowseButtonRoom = uibutton(app.UIFigure, 'push');
      app.BrowseButtonRoom.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonRoomPushed, true);
      app.BrowseButtonRoom.Text = 'Browse';
      app.BrowseButtonHP = uibutton(app.UIFigure, 'push');
      app.BrowseButtonHP.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonHPPushed, true);
      app.BrowseButtonHP.Text = 'Browse';
      app.CombineToBRIR = uicheckbox(app.UIFigure);
      app.CombineToBRIR.Text = 'Combine';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = binaural

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end