classdef setActiveFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BRIRNameEditField          matlab.ui.control.EditField
        BRIRNameEditFieldLabel     matlab.ui.control.Label
        BrowseButtonBRIR           matlab.ui.control.Button
        ActiveFilterSettingsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButtonBRIR
        function BrowseButtonBRIRPushed(app, event)
      sSelPath = uigetdir('BRIR/','Select BRIR folder'); figure(app.UIFigure);
      if ispc
        sPath    = extractAfter(sSelPath,'\BRIR\');
      else % macOS
        sPath    = extractAfter(sSelPath,'/BRIR/');
      end
      set(app.BRIRNameEditField,'Value',sPath);
      sRoomName       = string(extractBetween(sPath,'Room_','_HP'));
      sHeadphoneName  = extractAfter(sPath,'HP_');
      if ispc
        sSetup          = extractBefore(sPath,'\Room');
        sBRIRName       = extractAfter(sPath,[sSetup,'\']);
      else
        sSetup          = extractBefore(sPath,'/Room');
        sBRIRName       = extractAfter(sPath,[sSetup,'/']);
      end
      save('utils/ActiveFilter','sRoomName','sHeadphoneName','sBRIRName','sSetup');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ActiveFilterSettingsLabel = uilabel(app.UIFigure);
            app.ActiveFilterSettingsLabel.Text = 'Active Filter Settings';
            app.BrowseButtonBRIR = uibutton(app.UIFigure, 'push');
            app.BrowseButtonBRIR.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonBRIRPushed, true);
            app.BrowseButtonBRIR.Text = 'Browse';
            app.BRIRNameEditFieldLabel = uilabel(app.UIFigure);
            app.BRIRNameEditFieldLabel.Text = 'BRIR Name';
            app.BRIRNameEditField = uieditfield(app.UIFigure, 'text');
            app.BRIRNameEditField.Value = 'Room_Testroom_HP_TestHP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setActiveFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end